classdef ClockApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        ReadClockGridLayout   matlab.ui.container.GridLayout
        ReadTimeLabel         matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        WriteClockGridLayout  matlab.ui.container.GridLayout
        GridLayout4           matlab.ui.container.GridLayout
        Label_2               matlab.ui.control.Label
        HourEditField         matlab.ui.control.NumericEditField
        MinuteEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        WriteClock
        ReadClock
        Timer
    end
    
    methods (Access = private)
        
        function updateWriteClock(app)
            h = app.HourEditField.Value;
            m = app.MinuteEditField.Value;
            s = 0;
            app.WriteClock.Time = sprintf("%d:%d:%d",h,m,s);
        end
        
        function updateReadClock(app)
            dt = datetime("now","Format","hh:mm:ss");
            app.ReadClock.Time = dt;
            app.ReadTimeLabel.Text = string(dt);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.WriteClock = AnalogClock(app.WriteClockGridLayout,...
                "ShowSecond",false);
            app.WriteClock.Layout.Row = 1;
            
            app.ReadClock = AnalogClock(app.ReadClockGridLayout);
            app.ReadClock.Layout.Row = 1;
            
            app.Timer = timer("ExecutionMode","fixedRate",...
                "Period",1,...
                "TimerFcn",@(~,~) app.updateReadClock());
            app.Timer.start();
        end

        % Value changed function: HourEditField
        function HourEditFieldValueChanged(app, event)
            value = app.HourEditField.Value;
            app.updateWriteClock();
        end

        % Value changed function: MinuteEditField
        function MinuteEditFieldValueChanged(app, event)
            value = app.MinuteEditField.Value;
            app.updateWriteClock();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Timer.stop();
            delete(app)
            
        end

        % Callback function
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            app.updateWriteClock();
        end

        % Size changed function: RightPanel
        function RightPanelSizeChanged(app, event)
            position = app.RightPanel.Position;
            app.updateWriteClock();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '時計の針はどこを指す？';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'いま';
            app.ReadClockGridLayout = uigridlayout(app.LeftPanel);
            app.ReadTimeLabel = uilabel(app.ReadClockGridLayout);
            app.ReadTimeLabel.Text = '00:00';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'しんくん';
            app.RightPanel.SizeChangedFcn = createCallbackFcn(app, @RightPanelSizeChanged, true);
            app.WriteClockGridLayout = uigridlayout(app.RightPanel);
            app.GridLayout4 = uigridlayout(app.WriteClockGridLayout);
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = ':';
            app.HourEditField = uieditfield(app.GridLayout4, 'numeric');
            app.HourEditField.Limits = [1 12];
            app.HourEditField.ValueDisplayFormat = '%2d';
            app.HourEditField.ValueChangedFcn = createCallbackFcn(app, @HourEditFieldValueChanged, true);
            app.HourEditField.Value = 12;
            app.MinuteEditField = uieditfield(app.GridLayout4, 'numeric');
            app.MinuteEditField.Limits = [0 59];
            app.MinuteEditField.ValueDisplayFormat = '%02d';
            app.MinuteEditField.ValueChangedFcn = createCallbackFcn(app, @MinuteEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClockApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end